classdef knncorregir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        BorrarButton                   matlab.ui.control.Button
        ClasificarButton               matlab.ui.control.Button
        DistanciaButtonGroup           matlab.ui.container.ButtonGroup
        EuclidianaButton               matlab.ui.control.RadioButton
        ManhattanButton                matlab.ui.control.RadioButton
        MinkowskiButton                matlab.ui.control.RadioButton
        NumerodeclasesSpinnerLabel     matlab.ui.control.Label
        NumerodeclasesSpinner          matlab.ui.control.Spinner
        ColoresdelasclasesButtonGroup  matlab.ui.container.ButtonGroup
        Clase1Button                   matlab.ui.control.RadioButton
        Clase2Button                   matlab.ui.control.RadioButton
        Clase3Button                   matlab.ui.control.RadioButton
        Clase4Button                   matlab.ui.control.RadioButton
        AgregarpuntosparaclasificarButton  matlab.ui.control.StateButton
        KSpinnerLabel                  matlab.ui.control.Label
        KSpinner                       matlab.ui.control.Spinner
        FronteradedecisionButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        xy =[]; %Matriz que almacena los vectores de los puntos que ya estan clasificados
        clase=[];
        sxy=[] %Matriz que almacena los vectores de los puntos a clasificar
        cmap;
    end
    
    methods (Access = public)
        function results = Main(app) 
            Sc = KNN(app,app.xy,app.clase,app.sxy);
            app.xy = [app.xy; app.sxy];
            app.clase = [app.clase, Sc];           
            PlotPoints2(app, Sc);           
        end
    end
    methods (Access = private)   
        function Sc = KNN(app, T, Tc, S)
            %T = matriz de entrenamiento, 
            %Tc = Matriz con las clases de cada punto.
            %S = Set a entrenar
            %Se calcula la distancia para cada uno de los datos a clasificar           
            for i=1:length(S(:,1))
                for j=1:length(T(:,1))
                    if app.EuclidianaButton.Value == true
                        distancia(j,i) = pdist2(S(i,:), ...
                        T(j,:),'euclidean');
                    elseif app.ManhattanButton.Value == true
                        distancia(j,i) = sum(abs(S(i,:)- ...
                        T(j,:)));
                    else
                        distancia(j,i) = pdist2(S(i,:), ...
                        T(j,:),'minkowski');
                    end
                end
                %ik almacena los indices de las k distancias mas cercanas
                %por cada uno de los datos a entrenar.
                [~, ik] = mink(distancia,app.KSpinner.Value); 
                %nc almacena las clases de los k vecinos mas proximos
                for k=1:length(ik(:,i))
                    nc(k,i) = Tc(ik(k,i));
                end
                %Almacena la moda(clases mas repetidas) del conjunto de
                %Sc almacena las clases de los k-vecinos mas proximos
                Sc(i) = mode(nc(:,i),'all');               
            end
        end
        
        function results = PlotDecisionBoundary(app)
            xrange = [-2 2];
            yrange = [-2 2];
            [xx1,xx2] = meshgrid(xrange(1):0.05:xrange(2), yrange(1):0.05:yrange(2));
            image_size = size(xx1);
            gridXY = [xx1(:) xx2(:)];
            Sc= KNN(app,app.xy,app.clase,gridXY);
            decisionmap = reshape(Sc, image_size);
            imagesc(app.UIAxes,xrange,yrange,decisionmap);
            colormap(app.UIAxes,app.cmap);
            %gscatter(xx1(:), xx2(:), Sc,"rbgy",".",13,"on",'Y','X');         
            PlotPoints(app); 
            app.sxy = [];
            Sc = [];
        end
        
        function results = PlotPoints(app)          
            for i=1:length(app.xy)
                if app.clase(i) == 1
                    plot(app.UIAxes,app.xy(i,1),app.xy(i,2),'xr', 'MarkerFaceColor', 'r');
                elseif app.clase(i)==2
                    plot(app.UIAxes,app.xy(i,1),app.xy(i,2),'*b', 'MarkerFaceColor', 'b');
                elseif app.clase(i)==3
                    plot(app.UIAxes,app.xy(i,1),app.xy(i,2),'dg', 'MarkerFaceColor', 'g');
                else
                    plot(app.UIAxes,app.xy(i,1),app.xy(i,2),'py', 'MarkerFaceColor', 'y');
                end
            end
        end
        
        function results = PlotPoints2(app,Sc)
            for i=1:length(app.sxy)
                if Sc(i) == 1
                    plot(app.UIAxes,app.sxy(i,1),app.sxy(i,2),'or', 'MarkerFaceColor', 'r');
                elseif Sc(i)==2
                    plot(app.UIAxes,app.sxy(i,1),app.sxy(i,2),'ob', 'MarkerFaceColor', 'b');
                elseif Sc(i)==3
                    plot(app.UIAxes,app.sxy(i,1),app.sxy(i,2),'og', 'MarkerFaceColor', 'g');
                else
                    plot(app.UIAxes,app.sxy(i,1),app.sxy(i,2),'oy', 'MarkerFaceColor', 'y');
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes,[-2 2 -2 2]);
        end

        % Button pushed function: BorrarButton
        function BorrarButtonPushed(app, event)
            app.UIAxes.cla;
            app.puntos = [];
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            axis(app.UIAxes,[-2 2 -2 2]);
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
            hold(app.UIAxes, 'on' )
            if app.AgregarpuntosparaclasificarButton.Value == true
                plot(app.UIAxes,loc(1),loc(2), 'oy', 'MarkerFaceColor', 'k');
                app.sxy = [app.sxy;loc(1),loc(2)];
            else
                if app.Clase1Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), 'xr', 'MarkerFaceColor', 'r');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase, 1];
                elseif app.Clase2Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), '*b', 'MarkerFaceColor', 'b');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,2];
                elseif app.Clase3Button.Value == true
                    plot(app.UIAxes,loc(1),loc(2), 'dg', 'MarkerFaceColor', 'g');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,3];
                else 
                    plot(app.UIAxes,loc(1),loc(2), 'py', 'MarkerFaceColor', 'y');
                    app.xy = [app.xy;loc(1),loc(2)];
                    app.clase = [app.clase,4];
                end
            end
            
        end

        % Button pushed function: ClasificarButton
        function ClasificarButtonPushed(app, event)
            if app.NumerodeclasesSpinner.Value == 2
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6];
            end
            Main(app)
        end

        % Value changed function: NumerodeclasesSpinner
        function NumerodeclasesSpinnerValueChanged(app, event)
            value = app.NumerodeclasesSpinner.Value;
            if value == 2
                app.Clase3Button.Visible = false;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6];
            elseif value == 3
                app.Clase3Button.Visible = true;
                app.Clase4Button.Visible = false;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6; 0.03 0.6 0.03];
            elseif value == 4
                app.Clase4Button.Visible = true;
                app.cmap = [0.6 0.03 0.03; 0.03 0.03 0.6; 0.03 0.6 0.03; 0.6 0.6 0.03];
            end
        end

        % Button pushed function: FronteradedecisionButton
        function FronteradedecisionButtonPushed(app, event)
            PlotDecisionBoundary(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'K-NN')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.BorrarButton = uibutton(app.UIFigure, 'push');
            app.BorrarButton.ButtonPushedFcn = createCallbackFcn(app, @BorrarButtonPushed, true);
            app.BorrarButton.Text = 'Borrar';
            app.ClasificarButton = uibutton(app.UIFigure, 'push');
            app.ClasificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClasificarButtonPushed, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.DistanciaButtonGroup = uibuttongroup(app.UIFigure);
            app.DistanciaButtonGroup.Title = 'Distancia';
            app.EuclidianaButton = uiradiobutton(app.DistanciaButtonGroup);
            app.EuclidianaButton.Text = 'Euclidiana';
            app.EuclidianaButton.Value = true;
            app.ManhattanButton = uiradiobutton(app.DistanciaButtonGroup);
            app.ManhattanButton.Text = 'Manhattan';
            app.MinkowskiButton = uiradiobutton(app.DistanciaButtonGroup);
            app.MinkowskiButton.Text = 'Minkowski';
            app.NumerodeclasesSpinnerLabel = uilabel(app.UIFigure);
            app.NumerodeclasesSpinnerLabel.Text = 'Numero de clases';
            app.NumerodeclasesSpinner = uispinner(app.UIFigure);
            app.NumerodeclasesSpinner.Limits = [2 4];
            app.NumerodeclasesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumerodeclasesSpinnerValueChanged, true);
            app.NumerodeclasesSpinner.Value = 2;
            app.ColoresdelasclasesButtonGroup = uibuttongroup(app.UIFigure);
            app.ColoresdelasclasesButtonGroup.Title = 'Colores de las clases';
            app.Clase1Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase1Button.Text = 'Clase 1';
            app.Clase1Button.Value = true;
            app.Clase2Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase2Button.Text = 'Clase 2';
            app.Clase3Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase3Button.Visible = 'off';
            app.Clase3Button.Text = {'Clase 3'; ''};
            app.Clase4Button = uiradiobutton(app.ColoresdelasclasesButtonGroup);
            app.Clase4Button.Visible = 'off';
            app.Clase4Button.Text = 'Clase 4';
            app.AgregarpuntosparaclasificarButton = uibutton(app.UIFigure, 'state');
            app.AgregarpuntosparaclasificarButton.Text = 'Agregar puntos para clasificar';
            app.KSpinnerLabel = uilabel(app.UIFigure);
            app.KSpinnerLabel.Text = 'K';
            app.KSpinner = uispinner(app.UIFigure);
            app.KSpinner.Step = 2;
            app.KSpinner.Limits = [1 9];
            app.KSpinner.Value = 3;
            app.FronteradedecisionButton = uibutton(app.UIFigure, 'push');
            app.FronteradedecisionButton.ButtonPushedFcn = createCallbackFcn(app, @FronteradedecisionButtonPushed, true);
            app.FronteradedecisionButton.Text = 'Frontera de decision';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = knncorregir

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end